classdef KT_Analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KTAirfoilsAnalysisUIFigure    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ResetAppButton                matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        UpdateButton                  matlab.ui.control.Button
        LabelMaxJoukowski             matlab.ui.control.Label
        LabelMinJoukowski             matlab.ui.control.Label
        SliderJoukowski               matlab.ui.control.Slider
        LabelMaxKrmnTrefftz           matlab.ui.control.Label
        LabelMinKrmnTrefftz           matlab.ui.control.Label
        SliderKrmnTrefftz             matlab.ui.control.Slider
        MaxSliderCamber               matlab.ui.control.Label
        MinSliderCamber               matlab.ui.control.Label
        SliderCamber                  matlab.ui.control.Slider
        MaxSliderThickness            matlab.ui.control.Label
        MinSliderThickness            matlab.ui.control.Label
        SliderThickness               matlab.ui.control.Slider
        KuttaConditionCheckBox        matlab.ui.control.CheckBox
        FillProfileCheckBox           matlab.ui.control.CheckBox
        KrmnTrefftzEditField          matlab.ui.control.NumericEditField
        KrmnTrefftzEditFieldLabel     matlab.ui.control.Label
        JoukowskiEditField            matlab.ui.control.NumericEditField
        JoukowskiEditFieldLabel       matlab.ui.control.Label
        xcoordLabel                   matlab.ui.control.Label
        ThicknessEditField            matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel       matlab.ui.control.Label
        ycoordLabel                   matlab.ui.control.Label
        CamberEditField               matlab.ui.control.NumericEditField
        CamberEditFieldLabel          matlab.ui.control.Label
        kLabel                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        cLabel                        matlab.ui.control.Label
        kgm3Label                     matlab.ui.control.Label
        PaLabel                       matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        SelectPlotDropDown            matlab.ui.control.DropDown
        AngleofAttackEditField        matlab.ui.control.NumericEditField
        AngleofAttackEditFieldLabel   matlab.ui.control.Label
        FluidDensityEditField         matlab.ui.control.NumericEditField
        FluidDensityEditFieldLabel    matlab.ui.control.Label
        StaticPressureEditField       matlab.ui.control.NumericEditField
        StaticPressureEditFieldLabel  matlab.ui.control.Label
        VelocityEditField             matlab.ui.control.NumericEditField
        VelocityEditFieldLabel        matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Thickness % Description
        params % Description
        transformData % Description
        meshData % Description
        Camber % Description
        KrmnTrefftz % Description
        Joukowski % Description
        Velocity % Description
        Pressure % Description
        Density % Description
        AngleOfAttack % Description
        SelectedFlowPlot % Description
        potentialData % Description
        fill % Description
        kutta % Description
    end
    
    methods (Access = private)
        
        function updateGeometryPlot(app)

            app.params = setupParameters(app.kutta, app.fill, app.Velocity, app.Pressure, app.Density, app.AngleOfAttack, app.Joukowski, app.KrmnTrefftz, app.Thickness, app.Camber);
            app.transformData = computeKTTransformation(app.params, app.meshData);
            
            plotAirfoilGeometry(app.UIAxes2, app.params, app.transformData, app.meshData);
            
        end
        
        function flowPlot(app)
            
            app.params = setupParameters(app.kutta, app.fill, app.Velocity, app.Pressure, app.Density, app.AngleOfAttack, app.Joukowski, app.KrmnTrefftz, app.Thickness, app.Camber);
            app.transformData = computeKTTransformation(app.params, app.meshData);
            [app.meshData, app.potentialData] = computeMeshAndPotentials(app.params);

            if app.SelectedFlowPlot == "Flow Streamlines"
                app.KTAirfoilsAnalysisUIFigure.HandleVisibility = "on";
                figure(app.KTAirfoilsAnalysisUIFigure);
                axes(app.UIAxes);
                plotFlowStreamlines(app.UIAxes, app.params, app.transformData, app.potentialData)
                app.KTAirfoilsAnalysisUIFigure.HandleVisibility = "off";
            elseif app.SelectedFlowPlot == "Velocity Map"
                plotVelocityAirfoil(app.UIAxes, app.params, app.transformData, app.potentialData)
            elseif app.SelectedFlowPlot == "Pressure Map"
                plotPressureAirfoil(app.UIAxes, app.params, app.transformData, app.potentialData)
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;

            app.Image.Visible = "off";

            app.Velocity = 1;
            app.Pressure = 101325;
            app.Density = 1.225;
            app.AngleOfAttack = 20;
            app.Joukowski = 1;
            app.KrmnTrefftz = 1.95;
            app.Thickness = -0.05;
            app.Camber = 0.15;
            app.fill = app.FillProfileCheckBox.Value;
            app.kutta = app.KuttaConditionCheckBox.Value;

            app.SliderThickness.Value = app.Thickness;
            app.SliderCamber.Value = app.Camber;
            app.SliderKrmnTrefftz.Value = app.KrmnTrefftz;
            app.SliderJoukowski.Value = app.Joukowski;

            app.params = setupParameters(app.kutta, app.fill, app.Velocity, app.Pressure, app.Density, app.AngleOfAttack, app.Joukowski, app.KrmnTrefftz, app.Thickness, app.Camber);
            [app.meshData, app.potentialData] = computeMeshAndPotentials(app.params);
            app.transformData = computeKTTransformation(app.params, app.meshData);
            filterData = filterTransformedMesh(app.params, app.transformData, app.potentialData);

            app.VelocityEditField.Value = app.params.v_inf;
            app.StaticPressureEditField.Value = app.params.p_inf;
            app.FluidDensityEditField.Value = app.params.rho;
            app.AngleofAttackEditField.Value = app.params.alfa_grad;
            app.JoukowskiEditField.Value = app.params.c;
            app.KrmnTrefftzEditField.Value = app.params.k;
            app.CamberEditField.Value = app.params.center_y;
            app.ThicknessEditField.Value = app.params.center_x;

            plotAirfoilGeometry(app.UIAxes2, app.params, app.transformData, app.meshData);

            app.KTAirfoilsAnalysisUIFigure.HandleVisibility = "on";
            figure(app.KTAirfoilsAnalysisUIFigure);
            axes(app.UIAxes);
            plotFlowStreamlines(app.UIAxes, app.params, app.transformData, app.potentialData)
            app.KTAirfoilsAnalysisUIFigure.HandleVisibility = "off";

            %exportapp(app.KTAirfoilsAnalysisUIFigure, 'MyAppUISnapshot.png')

        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            app.Velocity = app.VelocityEditField.Value;
            
        end

        % Value changed function: StaticPressureEditField
        function StaticPressureEditFieldValueChanged(app, event)
            app.Pressure = app.StaticPressureEditField.Value;

        end

        % Value changed function: FluidDensityEditField
        function FluidDensityEditFieldValueChanged(app, event)
            app.Density = app.FluidDensityEditField.Value;
            
        end

        % Value changed function: AngleofAttackEditField
        function AngleofAttackEditFieldValueChanged(app, event)
            app.AngleOfAttack = app.AngleofAttackEditField.Value;
            
        end

        % Value changed function: JoukowskiEditField
        function JoukowskiEditFieldValueChanged(app, event)
            app.Joukowski = app.JoukowskiEditField.Value;
            app.SliderJoukowski.Value = app.Joukowski;
            updateGeometryPlot(app);
        end

        % Value changed function: KrmnTrefftzEditField
        function KrmnTrefftzEditFieldValueChanged(app, event)
            app.KrmnTrefftz = app.KrmnTrefftzEditField.Value;
            app.SliderKrmnTrefftz.Value = app.KrmnTrefftz;
            updateGeometryPlot(app);
        end

        % Value changed function: CamberEditField
        function CamberEditFieldValueChanged(app, event)
            app.Camber = app.CamberEditField.Value;
            app.SliderCamber.Value = app.Camber;
            updateGeometryPlot(app);
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            app.Thickness = app.ThicknessEditField.Value;
            app.SliderThickness.Value = app.Thickness;
            updateGeometryPlot(app);
        end

        % Value changed function: SliderThickness
        function SliderThicknessValueChanged(app, event)
            % sliderValue = app.SliderThickness.Value;
            % roundedValue = round(sliderValue, 2);
            % app.SliderThickness.Value = roundedValue;
            % app.Thickness = roundedValue;
            app.Thickness = app.SliderThickness.Value;
            app.ThicknessEditField.Value = app.Thickness;
            updateGeometryPlot(app);
        end

        % Value changed function: SliderCamber
        function SliderCamberValueChanged(app, event)
            app.Camber = app.SliderCamber.Value;
            app.CamberEditField.Value = app.Camber;
            updateGeometryPlot(app);
        end

        % Value changed function: SliderKrmnTrefftz
        function SliderKrmnTrefftzValueChanged(app, event)
            app.KrmnTrefftz = app.SliderKrmnTrefftz.Value;
            app.KrmnTrefftzEditField.Value = app.KrmnTrefftz;
            updateGeometryPlot(app);
        end

        % Value changed function: SliderJoukowski
        function SliderJoukowskiValueChanged(app, event)
            app.Joukowski = app.SliderJoukowski.Value;
            app.JoukowskiEditField.Value = app.Joukowski;
            updateGeometryPlot(app);
        end

        % Value changed function: SelectPlotDropDown
        function SelectPlotDropDownValueChanged(app, event)
            app.UpdateButton.Enable = 'off';
            app.Image.Visible = "on";
            drawnow
            app.SelectedFlowPlot = app.SelectPlotDropDown.Value;
            flowPlot(app);
            app.Image.Visible = "off";
            app.UpdateButton.Enable = 'on';
        end

        % Value changed function: FillProfileCheckBox
        function FillProfileCheckBoxValueChanged(app, event)
            app.Image.Visible = "on";
            drawnow
            app.fill = app.FillProfileCheckBox.Value;
            flowPlot(app);
            app.Image.Visible = "off";
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.Image.Visible = "on";
            drawnow
            app.SelectedFlowPlot = app.SelectPlotDropDown.Value;
            flowPlot(app);
            app.Image.Visible = "off";
        end

        % Button pushed function: ResetAppButton
        function ResetAppButtonPushed(app, event)
            app.Image.Visible = "on";
            drawnow
            startupFcn(app);
            app.Image.Visible = "off";
        end

        % Value changed function: KuttaConditionCheckBox
        function KuttaConditionCheckBoxValueChanged(app, event)
            app.Image.Visible = "on";
            drawnow
            app.kutta = app.KuttaConditionCheckBox.Value;
            flowPlot(app);
            app.Image.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.KTAirfoilsAnalysisUIFigure = uifigure('Visible', 'off');
            app.KTAirfoilsAnalysisUIFigure.Name = 'KT Airfoils Analysis';
            app.KTAirfoilsAnalysisUIFigure.Icon = fullfile(pathToMLAPP, 'KT_Airfoils_Analysis_LOGO-removebg-preview.png');
            app.GridLayout = uigridlayout(app.KTAirfoilsAnalysisUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VelocityEditFieldLabel = uilabel(app.GridLayout);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.GridLayout, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.StaticPressureEditFieldLabel = uilabel(app.GridLayout);
            app.StaticPressureEditFieldLabel.Text = 'Static Pressure';
            app.StaticPressureEditField = uieditfield(app.GridLayout, 'numeric');
            app.StaticPressureEditField.ValueChangedFcn = createCallbackFcn(app, @StaticPressureEditFieldValueChanged, true);
            app.FluidDensityEditFieldLabel = uilabel(app.GridLayout);
            app.FluidDensityEditFieldLabel.Text = 'Fluid Density';
            app.FluidDensityEditField = uieditfield(app.GridLayout, 'numeric');
            app.FluidDensityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidDensityEditFieldValueChanged, true);
            app.AngleofAttackEditFieldLabel = uilabel(app.GridLayout);
            app.AngleofAttackEditFieldLabel.Text = 'Angle of Attack';
            app.AngleofAttackEditField = uieditfield(app.GridLayout, 'numeric');
            app.AngleofAttackEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofAttackEditFieldValueChanged, true);
            app.SelectPlotDropDown = uidropdown(app.GridLayout);
            app.SelectPlotDropDown.Items = {'Flow Streamlines', 'Velocity Map', 'Pressure Map'};
            app.SelectPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPlotDropDownValueChanged, true);
            app.SelectPlotDropDown.Value = 'Flow Streamlines';
            app.msLabel = uilabel(app.GridLayout);
            app.msLabel.Text = 'm/s';
            app.PaLabel = uilabel(app.GridLayout);
            app.PaLabel.Text = 'Pa';
            app.kgm3Label = uilabel(app.GridLayout);
            app.kgm3Label.Interpreter = 'tex';
            app.kgm3Label.Text = 'kg/m^3';
            app.cLabel = uilabel(app.GridLayout);
            app.cLabel.Text = '(c)';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '°';
            app.kLabel = uilabel(app.GridLayout);
            app.kLabel.Text = '(k)';
            app.CamberEditFieldLabel = uilabel(app.GridLayout);
            app.CamberEditFieldLabel.Text = 'Camber';
            app.CamberEditField = uieditfield(app.GridLayout, 'numeric');
            app.CamberEditField.Limits = [0 1];
            app.CamberEditField.ValueChangedFcn = createCallbackFcn(app, @CamberEditFieldValueChanged, true);
            app.CamberEditField.Value = 0.15;
            app.ycoordLabel = uilabel(app.GridLayout);
            app.ycoordLabel.Text = '(y-coord)';
            app.ThicknessEditFieldLabel = uilabel(app.GridLayout);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.ThicknessEditField.Limits = [-0.5 -0.01];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = -0.05;
            app.xcoordLabel = uilabel(app.GridLayout);
            app.xcoordLabel.Text = '(x-coord)';
            app.JoukowskiEditFieldLabel = uilabel(app.GridLayout);
            app.JoukowskiEditFieldLabel.Text = 'Joukowski';
            app.JoukowskiEditField = uieditfield(app.GridLayout, 'numeric');
            app.JoukowskiEditField.Limits = [0.5 1.5];
            app.JoukowskiEditField.ValueChangedFcn = createCallbackFcn(app, @JoukowskiEditFieldValueChanged, true);
            app.JoukowskiEditField.Value = 1;
            app.KrmnTrefftzEditFieldLabel = uilabel(app.GridLayout);
            app.KrmnTrefftzEditFieldLabel.Text = 'Kármán-Trefftz';
            app.KrmnTrefftzEditField = uieditfield(app.GridLayout, 'numeric');
            app.KrmnTrefftzEditField.Limits = [1 2];
            app.KrmnTrefftzEditField.ValueChangedFcn = createCallbackFcn(app, @KrmnTrefftzEditFieldValueChanged, true);
            app.KrmnTrefftzEditField.Value = 1.95;
            app.FillProfileCheckBox = uicheckbox(app.GridLayout);
            app.FillProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillProfileCheckBoxValueChanged, true);
            app.FillProfileCheckBox.Text = 'Fill Profile';
            app.FillProfileCheckBox.Value = true;
            app.KuttaConditionCheckBox = uicheckbox(app.GridLayout);
            app.KuttaConditionCheckBox.ValueChangedFcn = createCallbackFcn(app, @KuttaConditionCheckBoxValueChanged, true);
            app.KuttaConditionCheckBox.Text = 'Kutta Condition';
            app.KuttaConditionCheckBox.Value = true;
            app.SliderThickness = uislider(app.GridLayout);
            app.SliderThickness.Limits = [-0.5 -0.01];
            app.SliderThickness.MajorTicks = [];
            app.SliderThickness.MajorTickLabels = {''};
            app.SliderThickness.ValueChangedFcn = createCallbackFcn(app, @SliderThicknessValueChanged, true);
            app.SliderThickness.MinorTicks = [];
            app.SliderThickness.Value = -0.05;
            app.MinSliderThickness = uilabel(app.GridLayout);
            app.MinSliderThickness.Text = '-0.5';
            app.MaxSliderThickness = uilabel(app.GridLayout);
            app.MaxSliderThickness.Text = '-0.01';
            app.SliderCamber = uislider(app.GridLayout);
            app.SliderCamber.Limits = [0 1];
            app.SliderCamber.MajorTicks = [];
            app.SliderCamber.ValueChangedFcn = createCallbackFcn(app, @SliderCamberValueChanged, true);
            app.SliderCamber.MinorTicks = [];
            app.SliderCamber.Value = 0.3;
            app.MinSliderCamber = uilabel(app.GridLayout);
            app.MinSliderCamber.Text = '0';
            app.MaxSliderCamber = uilabel(app.GridLayout);
            app.MaxSliderCamber.Text = '1';
            app.SliderKrmnTrefftz = uislider(app.GridLayout);
            app.SliderKrmnTrefftz.Limits = [1 2];
            app.SliderKrmnTrefftz.MajorTicks = [];
            app.SliderKrmnTrefftz.ValueChangedFcn = createCallbackFcn(app, @SliderKrmnTrefftzValueChanged, true);
            app.SliderKrmnTrefftz.MinorTicks = [];
            app.SliderKrmnTrefftz.Value = 1.95;
            app.LabelMinKrmnTrefftz = uilabel(app.GridLayout);
            app.LabelMinKrmnTrefftz.Text = '1';
            app.LabelMaxKrmnTrefftz = uilabel(app.GridLayout);
            app.LabelMaxKrmnTrefftz.Text = '2';
            app.SliderJoukowski = uislider(app.GridLayout);
            app.SliderJoukowski.Limits = [0.5 1.5];
            app.SliderJoukowski.MajorTicks = [];
            app.SliderJoukowski.ValueChangedFcn = createCallbackFcn(app, @SliderJoukowskiValueChanged, true);
            app.SliderJoukowski.MinorTicks = [];
            app.SliderJoukowski.Value = 1;
            app.LabelMinJoukowski = uilabel(app.GridLayout);
            app.LabelMinJoukowski.Text = '0.5';
            app.LabelMaxJoukowski = uilabel(app.GridLayout);
            app.LabelMaxJoukowski.Text = '1.5';
            app.UpdateButton = uibutton(app.GridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Spinner.gif');
            app.ResetAppButton = uibutton(app.GridLayout, 'push');
            app.ResetAppButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAppButtonPushed, true);
            app.ResetAppButton.Text = 'Reset App';
            % Show the figure after all components are created
            app.KTAirfoilsAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KT_Analysis_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KTAirfoilsAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KTAirfoilsAnalysisUIFigure)
        end
    end
end